classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        sampleRateEditField            matlab.ui.control.NumericEditField
        sampleRateEditFieldLabel       matlab.ui.control.Label
        savedataButton                 matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        reloadButton                   matlab.ui.control.Button
        GotoButton                     matlab.ui.control.Button
        zEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        exitButton                     matlab.ui.control.Button
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        resetButton                    matlab.ui.control.Button
        Lamp_2                         matlab.ui.control.Lamp
        specimen_lengthEditField       matlab.ui.control.NumericEditField
        homezButton                    matlab.ui.control.Button
        homexyButton                   matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        specimen_lengthEditFieldLabel  matlab.ui.control.Label
        scanvelocityEditField          matlab.ui.control.NumericEditField
        scanvelocityEditFieldLabel     matlab.ui.control.Label
        torquesearchButton             matlab.ui.control.Button
        distance                       matlab.ui.control.Slider
        mmSliderLabel                  matlab.ui.control.Label
        ANSWERLabel                    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        velocity                       matlab.ui.control.Slider
        Label_2                        matlab.ui.control.Label
        send_msg                       matlab.ui.control.Button
        command                        matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        ZLabel                         matlab.ui.control.Label
        up                             matlab.ui.control.Button
        down                           matlab.ui.control.Button
        StartscanButton                matlab.ui.control.Button
        left                           matlab.ui.control.Button
        right                          matlab.ui.control.Button
        back                           matlab.ui.control.Button
        front                          matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        ButtonGroup                    matlab.ui.container.ButtonGroup
        zButton                        matlab.ui.control.RadioButton
        yButton                        matlab.ui.control.RadioButton
        xButton                        matlab.ui.control.RadioButton
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        controller = plane_controller("COM3","COM4"); % Description
        data=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: up
        function upButtonPushed(app, event)
            pos = app.controller.pos_z();

            app.controller.move_z_ABS(pos-app.distance.Value,app.velocity.Value)
        end

        % Button pushed function: down
        function downButtonPushed(app, event)
            pos = app.controller.pos_z();

            app.controller.move_z_ABS(pos+app.distance.Value,app.velocity.Value)
        end

        % Button pushed function: front
        function frontButtonPushed(app, event)

            app.controller.move_y_INC(-app.distance.Value,app.velocity.Value)
        end

        % Button pushed function: back
        function backButtonPushed(app, event)

            app.controller.move_y_INC(app.distance.Value,app.velocity.Value)
        end

        % Button pushed function: left
        function leftButtonPushed(app, event)

            app.controller.move_x_INC(app.distance.Value,app.velocity.Value)
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)

            app.controller.move_x_INC(-app.distance.Value,app.velocity.Value)
        end

        % Button pushed function: send_msg
        function send_msgButtonPushed(app, event)
            s = app.command.Value;
            if app.ButtonGroup.SelectedObject==app.zButton
                response = app.controller.send_z(s);
            end
            if app.ButtonGroup.SelectedObject==app.xButton
                response = app.controller.send_x(s);
            end
            if app.ButtonGroup.SelectedObject==app.yButton
                response = app.controller.send_y(s);
            end
            app.Label_3.Text = response;
        end

        % Button pushed function: homezButton
        function homezButtonPushed(app, event)
            app.controller.home_z();
        end

        % Button pushed function: homexyButton
        function homexyButtonPushed(app, event)
            app.controller.home_x();
            app.controller.home_y();
            
        end

        % Button pushed function: torquesearchButton
        function torquesearchButtonPushed(app, event)
            app.Lamp_2.Color = [1,1,1];
            torque = 70;
            start_search_dis = 180; %mm
            speed_percentage = 100;
            lift_off_dis = 0.5 ;%mm
            app.controller.send_z("06051B0000");
            app.controller.move_z_ABS(start_search_dis,10);
            app.controller.wait_z();
            app.controller.send_z("060400"+dec2hex(torque,4));
            app.controller.send_z("0620050000");
            app.controller.send_z("06201400"+dec2hex(speed_percentage,4));
            app.controller.send_z("06201E0002")
            app.controller.wait_z();
            pos = app.controller.pos_z();
            app.controller.move_z_ABS(pos-lift_off_dis,5);
            pause(1);
            app.Lamp_2.Color = [0,1,0];
        end

        % Button pushed function: StartscanButton
        function StartscanButtonPushed(app, event)
            app.Lamp.Color = [1,1,1];
            dq = daq("ni");
            dq.Rate=app.sampleRateEditField.Value;
            addinput(dq,"cDAQ1Mod2","ai0","Voltage");
            addinput(dq,"cDAQ1Mod2","ai1","Voltage");
            scan_velocity = app.scanvelocityEditField.Value;
            specimen_lenght = app.specimen_lengthEditField.Value;
            app.Lamp.Color = [1,0,0];
            app.controller.move_y_INC(specimen_lenght,scan_velocity);
            app.data = read(dq, seconds(specimen_lenght/scan_velocity));
            app.Lamp.Color = [0,1,0];
            data = app.data;
            real = app.UIAxes;
            plot(real,data.Time, data.cDAQ1Mod2_ai0);
            img = app.UIAxes_2;
            plot(img,data.Time, data.cDAQ1Mod2_ai1);
            mag = app.UIAxes_3;
            plot(mag,data.Time,(data.cDAQ1Mod2_ai1.^2+data.cDAQ1Mod2_ai0.^2).^(1/2));
            phase = app.UIAxes_4;
            plot(phase,data.Time,-atan2(data.cDAQ1Mod2_ai1,data.cDAQ1Mod2_ai0));
            file_name = sprintf('velocity_%d_defect_scan_Exp.mat', app.scanvelocityEditField.Value);
            save(file_name, 'data'); % Ensure 'app.data' is quoted to save the variable properly
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.controller.send_x("CLEARFAULTS");
            app.controller.send_y("CLEARFAULTS");
            app.controller.send_x("EN");
            app.controller.send_y("EN");
            app.controller.send_z("0620110001");
            app.controller.send_z("06201E0006");
            app.controller.send_z("0620110000");
        end

        % Callback function
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            value = round(75-value*0.75);
            app.controller.move_y_ABS(value,app.velocity.Value);
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            value = round(75-value*0.75);
            app.controller.move_x_ABS(value,app.velocity.Value);
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: GotoButton
        function GotoButtonPushed(app, event)
            app.controller.move_x_ABS(app.xEditField.Value,app.velocity.Value);
            app.controller.move_y_ABS(app.yEditField.Value,app.velocity.Value);
            app.controller.move_z_ABS(app.zEditField.Value,app.velocity.Value);
        end

        % Button pushed function: reloadButton
        function reloadButtonPushed(app, event)
            app.xEditField.Value = app.controller.pos_x;
            app.yEditField.Value = app.controller.pos_y;
            app.zEditField.Value = app.controller.pos_z();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Img')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Mag')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Phase')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.xButton = uiradiobutton(app.ButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.ButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.ButtonGroup);
            app.zButton.Text = 'z';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '平移台.png';
            app.front = uibutton(app.UIFigure, 'push');
            app.front.ButtonPushedFcn = createCallbackFcn(app, @frontButtonPushed, true);
            app.front.Text = '前';
            app.back = uibutton(app.UIFigure, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Text = '後';
            app.right = uibutton(app.UIFigure, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '右';
            app.left = uibutton(app.UIFigure, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.left.Text = '左';
            app.StartscanButton = uibutton(app.UIFigure, 'push');
            app.StartscanButton.ButtonPushedFcn = createCallbackFcn(app, @StartscanButtonPushed, true);
            app.StartscanButton.Text = 'Start scan';
            app.down = uibutton(app.UIFigure, 'push');
            app.down.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.down.Text = '下';
            app.up = uibutton(app.UIFigure, 'push');
            app.up.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.up.Text = '上';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '指令';
            app.command = uitextarea(app.UIFigure);
            app.send_msg = uibutton(app.UIFigure, 'push');
            app.send_msg.ButtonPushedFcn = createCallbackFcn(app, @send_msgButtonPushed, true);
            app.send_msg.Text = 'send_msg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '速度';
            app.velocity = uislider(app.UIFigure);
            app.velocity.Value = 50;
            app.Label_3 = uilabel(app.UIFigure);
            app.ANSWERLabel = uilabel(app.UIFigure);
            app.ANSWERLabel.Text = 'ANSWER';
            app.mmSliderLabel = uilabel(app.UIFigure);
            app.mmSliderLabel.Text = '距離(mm)';
            app.distance = uislider(app.UIFigure);
            app.distance.Value = 50;
            app.torquesearchButton = uibutton(app.UIFigure, 'push');
            app.torquesearchButton.ButtonPushedFcn = createCallbackFcn(app, @torquesearchButtonPushed, true);
            app.torquesearchButton.Text = 'torque search';
            app.scanvelocityEditFieldLabel = uilabel(app.UIFigure);
            app.scanvelocityEditFieldLabel.Text = 'scan velocity';
            app.scanvelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.scanvelocityEditField.Limits = [0 100];
            app.scanvelocityEditField.Value = 10;
            app.specimen_lengthEditFieldLabel = uilabel(app.UIFigure);
            app.specimen_lengthEditFieldLabel.Text = 'specimen_length';
            app.Lamp = uilamp(app.UIFigure);
            app.homexyButton = uibutton(app.UIFigure, 'push');
            app.homexyButton.ButtonPushedFcn = createCallbackFcn(app, @homexyButtonPushed, true);
            app.homexyButton.Text = 'homexy';
            app.homezButton = uibutton(app.UIFigure, 'push');
            app.homezButton.ButtonPushedFcn = createCallbackFcn(app, @homezButtonPushed, true);
            app.homezButton.Text = 'homez';
            app.specimen_lengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.specimen_lengthEditField.Limits = [0 500];
            app.specimen_lengthEditField.Value = 220;
            app.Lamp_2 = uilamp(app.UIFigure);
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'exit';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.GotoButton = uibutton(app.UIFigure, 'push');
            app.GotoButton.ButtonPushedFcn = createCallbackFcn(app, @GotoButtonPushed, true);
            app.GotoButton.Text = 'Go to';
            app.reloadButton = uibutton(app.UIFigure, 'push');
            app.reloadButton.ButtonPushedFcn = createCallbackFcn(app, @reloadButtonPushed, true);
            app.reloadButton.Text = 'reload';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '未完成';
            app.savedataButton = uibutton(app.UIFigure, 'push');
            app.savedataButton.Text = 'save data';
            app.sampleRateEditFieldLabel = uilabel(app.UIFigure);
            app.sampleRateEditFieldLabel.Text = 'sample Rate';
            app.sampleRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.sampleRateEditField.Limits = [0 100000];
            app.sampleRateEditField.Value = 5000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end